classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Menu                        matlab.ui.container.Menu
        TabGroup                    matlab.ui.container.TabGroup
        Tab                         matlab.ui.container.Tab
        RegistrationPanel           matlab.ui.container.Panel
        SignUpButton                matlab.ui.control.Button
        UsernameEditFieldLabel      matlab.ui.control.Label
        UsernameEditField           matlab.ui.control.EditField
        PasswordEditFieldLabel      matlab.ui.control.Label
        PasswordEditField           matlab.ui.control.EditField
        AuthenticationPanel         matlab.ui.container.Panel
        SignInButton                matlab.ui.control.Button
        UsernameEditField_2Label    matlab.ui.control.Label
        UsernameEditField_2         matlab.ui.control.EditField
        PasswordEditField_2Label    matlab.ui.control.Label
        PasswordEditField_2         matlab.ui.control.EditField
        OptionPanel                 matlab.ui.container.Panel
        DisplayTheTableButton       matlab.ui.control.Button
        TerminatetheSocketButton    matlab.ui.control.Button
        StatusEditFieldLabel        matlab.ui.control.Label
        StatusEditField             matlab.ui.control.EditField
        HEXTablePanel               matlab.ui.container.Panel
        IDLabel                     matlab.ui.control.Label
        PasswordLabel               matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        EditField                   matlab.ui.control.EditField
        EditField_2                 matlab.ui.control.EditField
        BINARYTablePanel            matlab.ui.container.Panel
        IDLabel_2                   matlab.ui.control.Label
        PasswordLabel_2             matlab.ui.control.Label
        EditField_3                 matlab.ui.control.EditField
        EditField_4                 matlab.ui.control.EditField
        ROWandCOLAddressXORPanel    matlab.ui.container.Panel
        ROWLabel                    matlab.ui.control.Label
        COLLabel                    matlab.ui.control.Label
        EditField_5                 matlab.ui.control.EditField
        EditField_6                 matlab.ui.control.EditField
        HashTablePanel              matlab.ui.container.Panel
        EditField_7                 matlab.ui.control.EditField
        PairandChallengeTablePanel  matlab.ui.container.Panel
        PairsLabel                  matlab.ui.control.Label
        ChallengesLabel             matlab.ui.control.Label
        EditField_8                 matlab.ui.control.EditField
        EditField_9                 matlab.ui.control.EditField
        EditField_10                matlab.ui.control.EditField
        EditField_11                matlab.ui.control.EditField
        EditField_12                matlab.ui.control.EditField
        EditField_13                matlab.ui.control.EditField
        EditField_14                matlab.ui.control.EditField
        EditField_15                matlab.ui.control.EditField
        EditField_16                matlab.ui.control.EditField
        EditField_17                matlab.ui.control.EditField
        EditField_18                matlab.ui.control.EditField
        EditField_19                matlab.ui.control.EditField
        EditField_20                matlab.ui.control.EditField
        EditField_21                matlab.ui.control.EditField
        EditField_22                matlab.ui.control.EditField
        EditField_23                matlab.ui.control.EditField
        EditField_24                matlab.ui.control.EditField
        EditField_25                matlab.ui.control.EditField
        EditField_26                matlab.ui.control.EditField
        EditField_27                matlab.ui.control.EditField
        EditField_28                matlab.ui.control.EditField
        EditField_29                matlab.ui.control.EditField
        EditField_30                matlab.ui.control.EditField
        EditField_31                matlab.ui.control.EditField
        RespLabel                   matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        PasswordManagementwithRingOscillatorasPUFLabel  matlab.ui.control.Label
        HashPSWDLabel               matlab.ui.control.Label
        Tab2                        matlab.ui.container.Tab
        UITable                     matlab.ui.control.Table
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            clear;
            Flag = 1;
            global ChallengeTable
            ChallengeTable = cell(16, 16);
            
            for i = 1:16
                for j = 1:16
                    ChallengeTable{i, j}{end + 1} = 2;
                end
            end
            
            
            Mode = 0;
            global EnableSocket
            EnableSocket = 1;
            if EnableSocket
                global t
                t = tcpip('199.1.1.3', 12345, 'NetworkRole', ...
                    'client', 'Timeout', 1);
                t.InputBufferSize = 2;
                fopen(t);
            end
            
        end

        % Value changed function: UsernameEditField
        function UsernameEditFieldValueChanged(app, event)
            global ID
            ID = app.UsernameEditField.Value;
        end

        % Value changed function: PasswordEditField
        function PasswordEditFieldValueChanged(app, event)
            global PSWD
            PSWD = app.PasswordEditField.Value;
        end

        % Button pushed function: SignUpButton
        function SignUpButtonPushed(app, event)
            global ID PSWD hexID hexPSWD EnableSocket ChallengeTable t
            fprintf("ID = %s\n", ID);
            fprintf("PASSWORD = %s\n", PSWD);
            hexID = dec2hex(ID);
            hexPSWD = dec2hex(PSWD);
            app.EditField.Value = hexID(1,1:2);
            app.EditField_2.Value = hexPSWD(1,1:2);
            
            
            firstofID = hexID(1, 1);
            firstPSWD = hexPSWD(1, 1);
            
            secofID = hexID(1, 2);
            secPSWD = hexPSWD(1, 2);
            
            binfirstofID = hexToBinaryVector(firstofID,4,'MSBFirst');
            binfirstofPSWD = hexToBinaryVector(firstPSWD,4,'MSBFirst');
            
            binSecofID = hexToBinaryVector(secofID,4,'MSBFirst');
            binSecofPswd = hexToBinaryVector(secPSWD,4,'MSBFirst');
            
%             app.EditField_3.Value = [binfirstofID, binSecofID];
%             app.EditField_4.Value = [binfirstofPSWD, binSecofPswd];
            
            %% ROW and COLOUMN address of the user and passworkd in BINARY.HEX.DEC
            
            
            ROWaddrsBin = xor(binfirstofID, binfirstofPSWD);
            COLAddrsBin = xor(binSecofID, binSecofPswd);
            
            ROWaddHex = binaryVectorToHex(ROWaddrsBin);
            COLaddHex = binaryVectorToHex(COLAddrsBin);
            
            ROWaddDec = hex2dec(ROWaddHex) + 1;
            COLaddDec = hex2dec(COLaddHex) + 1;
            
            ROWaddDecChar = sprintf('%d', ROWaddDec);
            COLaddDecChar = sprintf('%d', COLaddDec);
            
            app.EditField_5.Value = (ROWaddDecChar);
            app.EditField_6.Value = (COLaddDecChar);
            
            
            %% Calcluate the Hash of the Password in order to get the Pair Query
            % THe output of the Hash function is Hex so because we need a challenge of
            % length 16 we take 32bits of the binary data of the Hashpassword. Each
            % line has 4 bits and we are going to use only 3 bits of that because our
            % ring oscillator has only 8 lines of Ring.
            
            opt.Method = 'SHA-1';
            
            HashHexPsswrd = DataHash(PSWD, opt);
            
            disp(HashHexPsswrd);
            app.EditField_7.Value = (HashHexPsswrd);
            
            HashbinPsswrd = ...
                hexToBinaryVector(HashHexPsswrd(1,1:32)', 4, 'MSBFirst');
            
            HashbinPsswrd3d = HashbinPsswrd(:, 2: end);
            LineReq = bi2de(HashbinPsswrd3d,'left-msb');
            
            index = 1;
            i = 1;
            PairReq = zeros(length(LineReq)/2, 2);
            while index <= length(LineReq)
                if mod(index, 2) == 1
                    PairReq(i, 1) = LineReq(index);
                else
                    PairReq(i, 2) = LineReq(index);
                    i = i + 1;
                end
                index = index + 1;
            end
            PairReq;
            
            app.EditField_8.Value = sprintf('%d - %d', PairReq(1, 1), PairReq(1,2));
            app.EditField_9.Value = sprintf('%d - %d', PairReq(2, 1), PairReq(2,2));
            app.EditField_10.Value = sprintf('%d - %d', PairReq(3, 1), PairReq(3,2));
            app.EditField_11.Value = sprintf('%d - %d', PairReq(4, 1), PairReq(4,2));
            app.EditField_12.Value = sprintf('%d - %d', PairReq(5, 1), PairReq(5,2));
            app.EditField_13.Value = sprintf('%d - %d', PairReq(6, 1), PairReq(6,2));
            app.EditField_14.Value = sprintf('%d - %d', PairReq(7, 1), PairReq(7,2));
            app.EditField_15.Value = sprintf('%d - %d', PairReq(8, 1), PairReq(8,2));
            
            %% Sending Pair Queries to the PUF to RaspberryPi and Receving it
            if EnableSocket
                i = 1;
                sizeOfPair = size(PairReq);
                data1 = zeros(1, sizeOfPair(1,1)/2);
                while i <= sizeOfPair(1,1)
                    SendData = sprintf("%d%d", PairReq(i, 1), PairReq(i, 2));
                    fwrite(t, SendData);
                    
                    data1(1, i) = fread(t, 1);
                    fprintf('Challenge = %c \n', char(data1(1,i)'));
                    i = i + 1;
                    %     pause(2);
                end
                
                ChallengeTable{ROWaddDec, COLaddDec}{end + 1} = char(data1);
                app.EditField_16.Value = char(data1(1,1));
                app.EditField_17.Value = char(data1(1,2));
                app.EditField_18.Value = char(data1(1,3)); 
                app.EditField_19.Value = char(data1(1,4));
                app.EditField_20.Value = char(data1(1,5));
                app.EditField_21.Value = char(data1(1,6));
                app.EditField_22.Value = char(data1(1,7));
                app.EditField_23.Value = char(data1(1,8));
                
            end
            
%                     ChallengeTable{ROWaddDec, COLaddDec}{end + 1} = HashHexPsswrd;
            fprintf('Registered !!! \n');
            fprintf('--------------------------------------------------------------------\n');
        end

        % Callback function
        function EditFieldValueChanged(app, event)
         
        end

        % Value changed function: UsernameEditField_2
        function UsernameEditField_2ValueChanged(app, event)
            global ID
            ID = app.UsernameEditField_2.Value;
        end

        % Value changed function: PasswordEditField_2
        function PasswordEditField_2ValueChanged(app, event)
            global PSWD
            PSWD = app.PasswordEditField_2.Value;
        end

        % Button pushed function: SignInButton
        function SignInButtonPushed(app, event)
            global ID PSWD hexID hexPSWD EnableSocket ChallengeTable t
            fprintf("ID = %s\n", ID);
            fprintf("PASSWORD = %s\n", PSWD);
            hexID = dec2hex(ID);
            hexPSWD = dec2hex(PSWD);
            app.EditField.Value = hexID(1,1:2);
            app.EditField_2.Value = hexPSWD(1,1:2);
            
            
            firstofID = hexID(1, 1);
            firstPSWD = hexPSWD(1, 1);
            
            secofID = hexID(1, 2);
            secPSWD = hexPSWD(1, 2);
            
            binfirstofID = hexToBinaryVector(firstofID,4,'MSBFirst');
            binfirstofPSWD = hexToBinaryVector(firstPSWD,4,'MSBFirst');
            
            binSecofID = hexToBinaryVector(secofID,4,'MSBFirst');
            binSecofPswd = hexToBinaryVector(secPSWD,4,'MSBFirst');
            
%             app.EditField_3.Value = [binfirstofID, binSecofID];
%             app.EditField_4.Value = [binfirstofPSWD, binSecofPswd];
            
            %% ROW and COLOUMN address of the user and passworkd in BINARY.HEX.DEC
            
            
            ROWaddrsBin = xor(binfirstofID, binfirstofPSWD);
            COLAddrsBin = xor(binSecofID, binSecofPswd);
            
            ROWaddHex = binaryVectorToHex(ROWaddrsBin);
            COLaddHex = binaryVectorToHex(COLAddrsBin);
            
            ROWaddDec = hex2dec(ROWaddHex) + 1;
            COLaddDec = hex2dec(COLaddHex) + 1;
            
            ROWaddDecChar = sprintf('%d', ROWaddDec);
            COLaddDecChar = sprintf('%d', COLaddDec);
            
            app.EditField_5.Value = (ROWaddDecChar);
            app.EditField_6.Value = (COLaddDecChar);
            
            
            %% Calcluate the Hash of the Password in order to get the Pair Query
            % THe output of the Hash function is Hex so because we need a challenge of
            % length 16 we take 32bits of the binary data of the Hashpassword. Each
            % line has 4 bits and we are going to use only 3 bits of that because our
            % ring oscillator has only 8 lines of Ring.
            
            opt.Method = 'SHA-1';
            
            HashHexPsswrd = DataHash(PSWD, opt);
            
            disp(HashHexPsswrd);
            app.EditField_7.Value = (HashHexPsswrd);
            
            HashbinPsswrd = ...
                hexToBinaryVector(HashHexPsswrd(1,1:32)', 4, 'MSBFirst');
            
            HashbinPsswrd3d = HashbinPsswrd(:, 2: end);
            LineReq = bi2de(HashbinPsswrd3d,'left-msb');
            
            index = 1;
            i = 1;
            PairReq = zeros(length(LineReq)/2, 2);
            while index <= length(LineReq)
                if mod(index, 2) == 1
                    PairReq(i, 1) = LineReq(index);
                else
                    PairReq(i, 2) = LineReq(index);
                    i = i + 1;
                end
                index = index + 1;
            end
            PairReq;
            
            app.EditField_8.Value = sprintf('%d - %d', PairReq(1, 1), PairReq(1,2));
            app.EditField_9.Value = sprintf('%d - %d', PairReq(2, 1), PairReq(2,2));
            app.EditField_10.Value = sprintf('%d - %d', PairReq(3, 1), PairReq(3,2));
            app.EditField_11.Value = sprintf('%d - %d', PairReq(4, 1), PairReq(4,2));
            app.EditField_12.Value = sprintf('%d - %d', PairReq(5, 1), PairReq(5,2));
            app.EditField_13.Value = sprintf('%d - %d', PairReq(6, 1), PairReq(6,2));
            app.EditField_14.Value = sprintf('%d - %d', PairReq(7, 1), PairReq(7,2));
            app.EditField_15.Value = sprintf('%d - %d', PairReq(8, 1), PairReq(8,2));
            
            %% Sending Pair Queries to the PUF to RaspberryPi and Receving it
            if EnableSocket
                i = 1;
                sizeOfPair = size(PairReq);
                data1 = zeros(1, sizeOfPair(1,1)/2);
                while i <= sizeOfPair(1,1)
                    SendData = sprintf("%d%d", PairReq(i, 1), PairReq(i, 2));
                    fwrite(t, SendData);
                    
                    data1(1, i) = fread(t, 1);
                    fprintf('Challenge = %c \n', char(data1(1,i)'));
                    i = i + 1;
                    %     pause(2);
                end
                
%                 ChallengeTable{ROWaddDec, COLaddDec}{end + 1} = char(data1);
                app.EditField_24.Value = char(data1(1,1));
                app.EditField_25.Value = char(data1(1,2));
                app.EditField_26.Value = char(data1(1,3)); 
                app.EditField_27.Value = char(data1(1,4));
                app.EditField_28.Value = char(data1(1,5));
                app.EditField_29.Value = char(data1(1,6));
                app.EditField_30.Value = char(data1(1,7));
                app.EditField_31.Value = char(data1(1,8));
            end
            
            isSame = cellfun(@(x)isequal(x,char(data1)),ChallengeTable{ROWaddDec, COLaddDec});
            [row,col] = find(isSame);
%             isSame = 0;
            if isempty(find(isSame, 1))
                fprintf('Authentication Failed !!! \n');
                app.StatusEditField.Value = 'Failed !!!';
            else
                fprintf('Response and Challenge are the same !!! \n');
                fprintf('Approved !!! \n');
                app.StatusEditField.FontColor = '0,1,0';
                app.StatusEditField.Value = 'APPROVED !!!';
            end
            fprintf('--------------------------------------------------------------------\n');
        end

        % Button pushed function: DisplayTheTableButton
        function DisplayTheTableButtonPushed(app, event)
            global ChallengeTable
            disp(ChallengeTable)
            assignin('base','ChallengeTable',ChallengeTable)
            app.UITable.Data = ChallengeTable{1,2};
%             app.UITable.
        end

        % Button pushed function: TerminatetheSocketButton
        function TerminatetheSocketButtonPushed(app, event)
            global t EnableSocket
            if EnableSocket
                disp('Socket Closed !!!')
                fclose(t);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.RegistrationPanel = uipanel(app.Tab);
            app.RegistrationPanel.Title = 'Registration';
            app.SignUpButton = uibutton(app.RegistrationPanel, 'push');
            app.SignUpButton.ButtonPushedFcn = createCallbackFcn(app, @SignUpButtonPushed, true);
            app.SignUpButton.Text = 'Sign Up';
            app.UsernameEditFieldLabel = uilabel(app.RegistrationPanel);
            app.UsernameEditFieldLabel.Text = 'Username:';
            app.UsernameEditField = uieditfield(app.RegistrationPanel, 'text');
            app.UsernameEditField.ValueChangedFcn = createCallbackFcn(app, @UsernameEditFieldValueChanged, true);
            app.PasswordEditFieldLabel = uilabel(app.RegistrationPanel);
            app.PasswordEditFieldLabel.Text = 'Password:';
            app.PasswordEditField = uieditfield(app.RegistrationPanel, 'text');
            app.PasswordEditField.ValueChangedFcn = createCallbackFcn(app, @PasswordEditFieldValueChanged, true);
            app.AuthenticationPanel = uipanel(app.Tab);
            app.AuthenticationPanel.Title = 'Authentication';
            app.SignInButton = uibutton(app.AuthenticationPanel, 'push');
            app.SignInButton.ButtonPushedFcn = createCallbackFcn(app, @SignInButtonPushed, true);
            app.SignInButton.Text = 'Sign In';
            app.UsernameEditField_2Label = uilabel(app.AuthenticationPanel);
            app.UsernameEditField_2Label.Text = 'Username:';
            app.UsernameEditField_2 = uieditfield(app.AuthenticationPanel, 'text');
            app.UsernameEditField_2.ValueChangedFcn = createCallbackFcn(app, @UsernameEditField_2ValueChanged, true);
            app.PasswordEditField_2Label = uilabel(app.AuthenticationPanel);
            app.PasswordEditField_2Label.Text = 'Password:';
            app.PasswordEditField_2 = uieditfield(app.AuthenticationPanel, 'text');
            app.PasswordEditField_2.ValueChangedFcn = createCallbackFcn(app, @PasswordEditField_2ValueChanged, true);
            app.OptionPanel = uipanel(app.Tab);
            app.OptionPanel.Title = 'Option';
            app.DisplayTheTableButton = uibutton(app.OptionPanel, 'push');
            app.DisplayTheTableButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayTheTableButtonPushed, true);
            app.DisplayTheTableButton.Text = 'Display The Table';
            app.TerminatetheSocketButton = uibutton(app.OptionPanel, 'push');
            app.TerminatetheSocketButton.ButtonPushedFcn = createCallbackFcn(app, @TerminatetheSocketButtonPushed, true);
            app.TerminatetheSocketButton.Text = 'Terminate the Socket';
            app.StatusEditFieldLabel = uilabel(app.OptionPanel);
            app.StatusEditFieldLabel.Text = 'Status: ';
            app.StatusEditField = uieditfield(app.OptionPanel, 'text');
            app.HEXTablePanel = uipanel(app.Tab);
            app.HEXTablePanel.Title = 'HEX Table';
            app.IDLabel = uilabel(app.HEXTablePanel);
            app.IDLabel.Text = 'ID:';
            app.PasswordLabel = uilabel(app.HEXTablePanel);
            app.PasswordLabel.Text = 'Password:';
            app.Label = uilabel(app.HEXTablePanel);
            app.Label.Text = '';
            app.EditField = uieditfield(app.HEXTablePanel, 'text');
            app.EditField_2 = uieditfield(app.HEXTablePanel, 'text');
            app.BINARYTablePanel = uipanel(app.Tab);
            app.BINARYTablePanel.Title = 'BINARY Table';
            app.IDLabel_2 = uilabel(app.BINARYTablePanel);
            app.IDLabel_2.Text = 'ID:';
            app.PasswordLabel_2 = uilabel(app.BINARYTablePanel);
            app.PasswordLabel_2.Text = 'Password:';
            app.EditField_3 = uieditfield(app.BINARYTablePanel, 'text');
            app.EditField_4 = uieditfield(app.BINARYTablePanel, 'text');
            app.ROWandCOLAddressXORPanel = uipanel(app.Tab);
            app.ROWandCOLAddressXORPanel.Title = 'ROW and COL Address (XOR)';
            app.ROWLabel = uilabel(app.ROWandCOLAddressXORPanel);
            app.ROWLabel.Text = 'ROW:';
            app.COLLabel = uilabel(app.ROWandCOLAddressXORPanel);
            app.COLLabel.Text = 'COL:';
            app.EditField_5 = uieditfield(app.ROWandCOLAddressXORPanel, 'text');
            app.EditField_6 = uieditfield(app.ROWandCOLAddressXORPanel, 'text');
            app.HashTablePanel = uipanel(app.Tab);
            app.HashTablePanel.Title = 'Hash Table';
            app.EditField_7 = uieditfield(app.HashTablePanel, 'text');
            app.PairandChallengeTablePanel = uipanel(app.Tab);
            app.PairandChallengeTablePanel.Title = 'Pair and Challenge Table';
            app.PairsLabel = uilabel(app.PairandChallengeTablePanel);
            app.PairsLabel.Text = 'Pairs:';
            app.ChallengesLabel = uilabel(app.PairandChallengeTablePanel);
            app.ChallengesLabel.Text = 'Challenges:';
            app.EditField_8 = uieditfield(app.PairandChallengeTablePanel, 'text');
            app.EditField_9 = uieditfield(app.PairandChallengeTablePanel, 'text');
            app.EditField_10 = uieditfield(app.PairandChallengeTablePanel, 'text');
            app.EditField_11 = uieditfield(app.PairandChallengeTablePanel, 'text');
            app.EditField_12 = uieditfield(app.PairandChallengeTablePanel, 'text');
            app.EditField_13 = uieditfield(app.PairandChallengeTablePanel, 'text');
            app.EditField_14 = uieditfield(app.PairandChallengeTablePanel, 'text');
            app.EditField_15 = uieditfield(app.PairandChallengeTablePanel, 'text');
            app.EditField_16 = uieditfield(app.PairandChallengeTablePanel, 'text');
            app.EditField_17 = uieditfield(app.PairandChallengeTablePanel, 'text');
            app.EditField_18 = uieditfield(app.PairandChallengeTablePanel, 'text');
            app.EditField_19 = uieditfield(app.PairandChallengeTablePanel, 'text');
            app.EditField_20 = uieditfield(app.PairandChallengeTablePanel, 'text');
            app.EditField_21 = uieditfield(app.PairandChallengeTablePanel, 'text');
            app.EditField_22 = uieditfield(app.PairandChallengeTablePanel, 'text');
            app.EditField_23 = uieditfield(app.PairandChallengeTablePanel, 'text');
            app.EditField_24 = uieditfield(app.PairandChallengeTablePanel, 'text');
            app.EditField_25 = uieditfield(app.PairandChallengeTablePanel, 'text');
            app.EditField_26 = uieditfield(app.PairandChallengeTablePanel, 'text');
            app.EditField_27 = uieditfield(app.PairandChallengeTablePanel, 'text');
            app.EditField_28 = uieditfield(app.PairandChallengeTablePanel, 'text');
            app.EditField_29 = uieditfield(app.PairandChallengeTablePanel, 'text');
            app.EditField_30 = uieditfield(app.PairandChallengeTablePanel, 'text');
            app.EditField_31 = uieditfield(app.PairandChallengeTablePanel, 'text');
            app.RespLabel = uilabel(app.PairandChallengeTablePanel);
            app.RespLabel.Text = 'Resp:';
            app.Panel = uipanel(app.Tab);
            app.PasswordManagementwithRingOscillatorasPUFLabel = uilabel(app.Panel);
            app.PasswordManagementwithRingOscillatorasPUFLabel.Text = 'Password Management with Ring Oscillator as PUF';
            app.HashPSWDLabel = uilabel(app.Tab);
            app.HashPSWDLabel.Text = 'Hash(PSWD):';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UITable = uitable(app.Tab2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; 'Column 7'; 'Column 8'; 'Column 9'; 'Column 10'; 'Column 11'; 'Column 12'; 'Column 13'; 'Column 14'; 'Column 15'; 'Column 16'};
            app.UITable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end